classdef Practice_Sr_Design < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        ImportImageButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportImageButton
        function ImportImageButtonPushed(app, event)
            display('Load in the brain')
            [file_brain,path_brain] = uigetfile('*.nii.gz'); % saks the user to get the file
            if isequal(file_brain,0)
               disp('User selected Cancel');
            else
               disp(['User selected ', fullfile(path_brain,file_brain)]);
               imbrain = niftiread(file_brain); %creates the brain matrix
               assignin('base','imbrain',imbrain); %assigns the variable imbrain (the dowloaded image) as 'imbrain' in the workspace
            end
            
            display('Load in the mask')
            [file_mask,path_mask] = uigetfile('*.nii.gz'); % saks the user to get the file
            if isequal(file_mask,0)
               disp('User selected Cancel');
            else
               disp(['User selected ', fullfile(path_mask,file_mask)]);
               immask = niftiread(file_mask); %creates the mask matrix
               assignin('base','immask',immask); %assigns the variable immask (the dowloaded image) as 'imbrain' in the workspace
            end
            
            
            run('main.m');
            Show = uiaxes(app.UIFigure,"Position", [114 152 319 239]);
            imshow(bob,[],'parent',Show);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ImportImageButton = uibutton(app.UIFigure, 'push');
            app.ImportImageButton.ButtonPushedFcn = createCallbackFcn(app, @ImportImageButtonPushed, true);
            app.ImportImageButton.Text = 'Import Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Practice_Sr_Design

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end